classdef Dobot_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        q1SliderLabel     matlab.ui.control.Label
        q1Slider          matlab.ui.control.Slider
        q2SliderLabel     matlab.ui.control.Label
        q2Slider          matlab.ui.control.Slider
        q3SliderLabel     matlab.ui.control.Label
        q3Slider          matlab.ui.control.Slider
        StartMainButton   matlab.ui.control.Button
        XEditFieldLabel   matlab.ui.control.Label
        XEditField        matlab.ui.control.NumericEditField
        YEditFieldLabel   matlab.ui.control.Label
        YEditField        matlab.ui.control.NumericEditField
        ZEditFieldLabel   matlab.ui.control.Label
        ZEditField        matlab.ui.control.NumericEditField
        InverseButton     matlab.ui.control.Button
        estopButton       matlab.ui.control.StateButton
        ForwardButton     matlab.ui.control.Button
        InitialiseButton  matlab.ui.control.Button
        ContextMenu       matlab.ui.container.ContextMenu
        Menu              matlab.ui.container.Menu
        Menu2             matlab.ui.container.Menu
        q4SliderLabel     matlab.ui.control.Label
        q4Slider          matlab.ui.control.Slider
    end

    % Common variable to be accessed by all functions
    properties (Access = public)
        Property % Description
        dobot = Dobot().model;
        steps = 20;
        neutralQ = [0    1.3253   -1.4893    0.1676]
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitialiseButton
        function InitialiseButtonPushed(app, event)
            % Move the robot to the neutral position
            currentQ = app.dobot.getpos();
            qMat = jtraj(currentQ,app.neutralQ,app.steps);
            for i = 1:app.steps
                if ~app.estopButton.Value
                   app.dobot.animate(qMat(i,:));  
                end 
            end
            
            %update slider values
            app.q1Slider.Value = rad2deg(qMat(i,1));    
            app.q2Slider.Value = rad2deg(qMat(i,2)); 
            app.q3Slider.Value = rad2deg(qMat(i,3)); 
            app.q4Slider.Value = rad2deg(qMat(i,4));
            
            %update XYZ coordinates in textbox
            endTR = app.dobot.fkine(app.neutralQ);
            app.XEditField.Value = endTR(1,4);
            app.YEditField.Value = endTR(2,4);
            app.ZEditField.Value = endTR(3,4);
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            % Obtain slider values for joint angles
            q = deg2rad([app.q1Slider.Value, app.q2Slider.Value, app.q3Slider.Value, app.q4Slider.Value]);
            
            %update the XYZ coordinates for the joint angles in the textboxes
            endTR = app.dobot.fkine(q);
            app.XEditField.Value = endTR(1,4);
            app.YEditField.Value = endTR(2,4);
            app.ZEditField.Value = endTR(3,4);
            
            %draw trajectory for the robot if the estop is not applied
            currentQ = app.dobot.getpos();
            qMat = jtraj(currentQ,q,app.steps);
            for i = 1:app.steps
                if ~app.estopButton.Value
                   app.dobot.animate(qMat(i,:)); 
                end             
            end
        end

        % Button pushed function: InverseButton
        function InverseButtonPushed(app, event)
            % Gets the X,Y,Z coordinates from the text boxes written in by user
            goalLocation = [app.XEditField.Value,app.YEditField.Value,app.ZEditField.Value];
            
            % Gets the Q matrix for the trajectory of the robot
            qMat = GoGoal(app.dobot,goalLocation,app.neutralQ,app.steps);
            
            % Iterates the through the joint configurations and moves if estop is not pressed
            for i = 1:app.steps
                if ~app.estopButton.Value
                   app.dobot.animate(qMat(i,:));                
                end           
            end
            
            %update the slider values to the current joint positions
            app.q1Slider.Value = rad2deg(qMat(app.steps,1));    
            app.q2Slider.Value = rad2deg(qMat(app.steps,2)); 
            app.q3Slider.Value = rad2deg(qMat(app.steps,3));
            app.q4Slider.Value = rad2deg(qMat(app.steps,4));
            
            %Displays the actual XYZ coordinates of the end effector in the text box
            actualQ = app.dobot.getpos();
            actualTR = app.dobot.fkine(actualQ);
            app.XEditField.Value = actualTR(1,4);
            app.YEditField.Value = actualTR(2,4);
            app.ZEditField.Value = actualTR(3,4);
        end

        % Button pushed function: StartMainButton
        function StartMainButtonPushed(app, event)
            %add ball and cup locations here
           % [ballLocation,cupLocation] = [[]];
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.q1SliderLabel = uilabel(app.UIFigure);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.UIFigure);
            app.q1Slider.Limits = [-90.001 90.001];
            app.q1Slider.MajorTicks = [-90.001 -60.001 -30.001 -0.00100000000000477 29.999 59.999 90.001];
            app.q1Slider.MajorTickLabels = {'-90', '-60', '-30', '-0', '30', '60', '90'};
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.UIFigure);
            app.q2Slider.Limits = [-0.001 85.001];
            app.q2Slider.MajorTickLabels = {'0', '20', '40', '60', '85'};
            app.q3SliderLabel = uilabel(app.UIFigure);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.UIFigure);
            app.q3Slider.Limits = [-90.001 10.001];
            app.StartMainButton = uibutton(app.UIFigure, 'push');
            app.StartMainButton.ButtonPushedFcn = createCallbackFcn(app, @StartMainButtonPushed, true);
            app.StartMainButton.Text = 'Start Main';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.InverseButton = uibutton(app.UIFigure, 'push');
            app.InverseButton.ButtonPushedFcn = createCallbackFcn(app, @InverseButtonPushed, true);
            app.InverseButton.Text = 'Inverse';
            app.estopButton = uibutton(app.UIFigure, 'state');
            app.estopButton.Icon = 'estop-md.png';
            app.estopButton.Text = 'estop';
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.InitialiseButton = uibutton(app.UIFigure, 'push');
            app.InitialiseButton.ButtonPushedFcn = createCallbackFcn(app, @InitialiseButtonPushed, true);
            app.InitialiseButton.Text = 'Initialise';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            app.q4SliderLabel = uilabel(app.UIFigure);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.UIFigure);
            app.q4Slider.Limits = [-90.001 90.001];
            app.q4Slider.MajorTicks = [-90.001 -60.001 -30.001 -0.00100000000000477 29.999 59.999 90.001];
            app.q4Slider.MajorTickLabels = {'-90', '-60', '-30', '-0', '30', '60', '90'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dobot_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end